classdef trigger_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IOPanel                  matlab.ui.container.Panel
        TTLOutputButton          matlab.ui.control.Button
        LabelTTL_Output          matlab.ui.control.Label
        LabelTTL_input           matlab.ui.control.Label
        LabelTTLOutputCounter    matlab.ui.control.Label
        LabelTTLInputCounter     matlab.ui.control.Label
        LabelTTLOutputLapseTime  matlab.ui.control.Label
        LabelTTLInputLapseTime   matlab.ui.control.Label
        TTLOutputRestButton      matlab.ui.control.Button
        TTLInputRestButton       matlab.ui.control.Button
        ConnectPanel             matlab.ui.container.Panel
        PortSwitch               matlab.ui.control.Switch
        PortDropDown             matlab.ui.control.DropDown
        PortDropDownLabel        matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        PortObj;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortObj.status=0;
            app.PortObj.dev='';

            app.PortObj.input_clock=datetime("now");
            app.PortObj.output_clock=datetime("now");

            app.PortObj.TTL_input_counter=0;
            app.PortObj.TTL_output_counter=0;


            app.LabelTTLInputCounter.Enable='off';
            app.LabelTTLInputLapseTime.Enable='off';
            app.TTLInputRestButton.Enable='off';

            app.LabelTTLOutputCounter.Enable='off';
            app.LabelTTLOutputLapseTime.Enable='off';
            app.TTLOutputRestButton.Enable='off';
            app.TTLOutputButton.Enable='off';

        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)
            if(ismac)
                d=dir('/dev/cu*');
            end;

            if(~isempty(d))
                for d_idx=1:length(d)
                    p{d_idx}=d(d_idx).name;
                end;
                app.PortDropDown.Items=p;
                app.PortDropDown.Value=p{end};

                app.Lamp.Color='y';

                app.PortSwitch.Enable='on';
            end;
        end

        % Value changed function: PortSwitch
        function PortSwitchValueChanged(app, event)
            value = app.PortSwitch.Value;
            
            if(strcmp(value,'On'))
                    if(ismac)
                        port_name=sprintf('/dev/%s',app.PortDropDown.Value);
                    end;

                    try
                        %try open the port
                        app.PortObj.port = serialport(port_name,9600);

                        %setup callback
                        configureCallback(app.PortObj.port,"terminator",@trigger_monitor_readSerialData);
                        app.PortObj.port.UserData=app;
                        
%                        configureCallback(app.PortObj.port,"terminator",@this.func);

                        app.PortObj.dev=port_name;
                        app.PortObj.status=1;
                        TTL_input_counter=app.PortObj.TTL_input_counter;

                        app.Lamp.Color='g';

                        app.PortSwitch.Enable='on';

                        app.LabelTTLInputCounter.Enable='on';
                        app.LabelTTLInputLapseTime.Enable='on';
                        app.TTLInputRestButton.Enable='on';
                        
                        app.LabelTTLOutputCounter.Enable='on';
                        app.LabelTTLOutputLapseTime.Enable='on';
                        app.TTLOutputRestButton.Enable='on';
                        app.TTLOutputButton.Enable='on';
                        
                    catch
                        fprintf('error in opening [%s]!\n',port_name);

                        app.PortObj.port = [];
                        app.PortObj.dev='';
                        app.PortObj.status=0;
                        app.PortObj.TTL_input_counter=0;

                        app.LabelTTLInputCounter.Enable='off';
                        app.LabelTTLInputLapseTime.Enable='off';
                        app.TTLInputRestButton.Enable='off';

                        app.LabelTTLOutputCounter.Enable='off';
                        app.LabelTTLOutputLapseTime.Enable='off';
                        app.TTLOutputRestButton.Enable='off';
                        app.TTLOutputButton.Enable='off';

                        app.Lamp.Color='y';
                        
                        app.PortSwitch.Value='Off';
                    end;

            elseif(strcmp(value,'Off'))

                try
                    app.PortObj.port = [];
                    app.PortObj.dev='';
                    app.PortObj.status=0;
                    app.PortObj.TTL_input_counter=0;


                    app.LabelTTLInputCounter.Enable='off';
                    app.LabelTTLInputLapseTime.Enable='off';
                    app.TTLInputRestButton.Enable='off';

                    app.LabelTTLOutputCounter.Enable='off';
                    app.LabelTTLOutputLapseTime.Enable='off';
                    app.TTLOutputRestButton.Enable='off';
                    app.TTLOutputButton.Enable='off';

                    app.Lamp.Color='y';
                catch
                end;
            end;
        end

        % Button pushed function: TTLInputRestButton
        function TTLInputRestButtonPushed(app, event)
            app.PortObj.TTL_input_counter=0;
            app.LabelTTLInputCounter.Text=sprintf('%04d',0);
            app.LabelTTLInputLapseTime.Text=sprintf('---.---');
            app.PortObj.input_clock=datetime("now");
        end

        % Button pushed function: TTLOutputButton
        function TTLOutputButtonPushed(app, event)
            %configureCallback(app.PortObj.port,"off");
            %app.PortObj.port.writeline('x');
            write(app.PortObj.port,'x',"uint8");
            %configureCallback(app.PortObj.port,"terminator",@trigger_monitor_readSerialData);

            app.PortObj.TTL_output_counter=app.PortObj.TTL_output_counter+1;

            app.LabelTTLOutputCounter.Text=sprintf('%04d',app.PortObj.TTL_output_counter);

            clock_now=datetime("now");
            laps_time=clock_now-app.PortObj.output_clock;
            app.LabelTTLOutputLapseTime.Text=sprintf('%3.3f',seconds(laps_time));
        end

        % Button pushed function: TTLOutputRestButton
        function TTLOutputRestButtonPushed(app, event)
            app.PortObj.TTL_output_counter=0;
            app.LabelTTLOutputCounter.Text=sprintf('%04d',0);
            app.LabelTTLOutputLapseTime.Text=sprintf('---.---');
            app.PortObj.output_clock=datetime("now");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectPanel = uipanel(app.UIFigure);
            app.ConnectPanel.Title = 'Connect';
            app.Lamp = uilamp(app.ConnectPanel);
            app.PortDropDownLabel = uilabel(app.ConnectPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConnectPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.Value = {};
            app.PortSwitch = uiswitch(app.ConnectPanel, 'slider');
            app.PortSwitch.ValueChangedFcn = createCallbackFcn(app, @PortSwitchValueChanged, true);
            app.PortSwitch.Enable = 'off';
            app.IOPanel = uipanel(app.UIFigure);
            app.IOPanel.Title = 'I/O';
            app.TTLInputRestButton = uibutton(app.IOPanel, 'push');
            app.TTLInputRestButton.ButtonPushedFcn = createCallbackFcn(app, @TTLInputRestButtonPushed, true);
            app.TTLInputRestButton.Text = 'R';
            app.TTLOutputRestButton = uibutton(app.IOPanel, 'push');
            app.TTLOutputRestButton.ButtonPushedFcn = createCallbackFcn(app, @TTLOutputRestButtonPushed, true);
            app.TTLOutputRestButton.Text = 'R';
            app.LabelTTLInputLapseTime = uilabel(app.IOPanel);
            app.LabelTTLInputLapseTime.Text = '---:---';
            app.LabelTTLOutputLapseTime = uilabel(app.IOPanel);
            app.LabelTTLOutputLapseTime.Text = '---:---';
            app.LabelTTLInputCounter = uilabel(app.IOPanel);
            app.LabelTTLInputCounter.Text = '0000';
            app.LabelTTLOutputCounter = uilabel(app.IOPanel);
            app.LabelTTLOutputCounter.Text = '0000';
            app.LabelTTL_input = uilabel(app.IOPanel);
            app.LabelTTL_input.Text = 'TTL input: ';
            app.LabelTTL_Output = uilabel(app.IOPanel);
            app.LabelTTL_Output.Text = 'TTL output: ';
            app.TTLOutputButton = uibutton(app.IOPanel, 'push');
            app.TTLOutputButton.ButtonPushedFcn = createCallbackFcn(app, @TTLOutputButtonPushed, true);
            app.TTLOutputButton.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trigger_monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end